classdef Acclimate_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcclimateUIFigure           matlab.ui.Figure
        AcclimateLabel              matlab.ui.control.Label
        BoothEditFieldLabel         matlab.ui.control.Label
        BoothEditField              matlab.ui.control.NumericEditField
        rewarddurationsecEditFieldLabel  matlab.ui.control.Label
        rewarddurationsecEditField  matlab.ui.control.NumericEditField
        ITIminsecEditFieldLabel     matlab.ui.control.Label
        ITIminsecEditField          matlab.ui.control.NumericEditField
        ITImaxsecEditFieldLabel     matlab.ui.control.Label
        ITImaxsecEditField          matlab.ui.control.NumericEditField
        StartButton                 matlab.ui.control.Button
        RunningStatusLampLabel      matlab.ui.control.Label
        RunningStatusLamp           matlab.ui.control.Lamp
    end


    properties (Access = private)
        booth = 1;
        reward_time = 3;  % Set reward duration
        ITI_min = 30;     % Set ITI range (min and max)
        ITI_max = 60;     % If the range is too small, might cause the bird to be stressful
        
    end

    methods (Access = private)
    
        function acclimate(app)
            
            %connect to the arduino corresponding to the booth number
            switch app.booth
                 case 1
                    arduino_device = 'COM3';
                    disp('booth 1 running');
                case 2
                    arduino_device = 'COM4';
                    disp('booth 2 running');
                case 3
                    arduino_device = 'COM5';
                    disp('booth 3 running');
                case 4
                    arduino_device = 'COM7';
                    disp('booth 4 running');
                case 5
                    arduino_device = 'COM8';
                    disp('booth 5 running');
                case 6
                    arduino_device = 'COM9';
                    disp('booth 6 running');
                case 7
                    arduino_device = 'COM10';
                    disp('booth 7 running');
                case 8
                    arduino_device = 'COM6';
                    disp('booth 8 running');
            end
            
            % Initialize arduino
            a = arduino(arduino_device);
            
            % Define solenoid pin # and configure the pin to digital output
            SOLENOID_PIN = 'D46';
            configurePin(a, SOLENOID_PIN, 'digitalOutput');
            
            %% Actual experiment
            % The feeder moves after a random time interval and has the reward present for a defined time duration
            while true
                drawnow;   % Read Callbacks that have been run
                
                writeDigitalPin(a, SOLENOID_PIN, 0);
                interval = randi([app.ITI_min, app.ITI_max],1,1);
                disp(interval);
                pause(interval);
                          
                writeDigitalPin(a, SOLENOID_PIN, 1);
                pause(app.reward_time);
            end
            
        end
        
    end


    methods (Access = private)

        % Value changed function: rewarddurationsecEditField
        function rewarddurationsecEditFieldValueChanged(app, event)
            app.reward_time = app.rewarddurationsecEditField.Value;
            
        end

        % Value changed function: ITIminsecEditField
        function ITIminsecEditFieldValueChanged(app, event)
            app.ITI_min = app.ITIminsecEditField.Value;
            
        end

        % Value changed function: ITImaxsecEditField
        function ITImaxsecEditFieldValueChanged(app, event)
            app.ITI_max = app.ITImaxsecEditField.Value;
            
        end

        % Value changed function: BoothEditField
        function BoothEditFieldValueChanged(app, event)
            app.booth = app.BoothEditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if (app.RunningStatusLamp.Color == [1 0 0])
                
                app.RunningStatusLamp.Color = [0 1 0]; % Turn lamp color to green
                
                disp('Acclimate');
                acclimate(app);
                
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AcclimateUIFigure = uifigure;
            app.AcclimateUIFigure.Name = 'Acclimate';
            app.AcclimateLabel = uilabel(app.AcclimateUIFigure);
            app.AcclimateLabel.Text = 'Acclimate';
            app.BoothEditFieldLabel = uilabel(app.AcclimateUIFigure);
            app.BoothEditFieldLabel.Text = 'Booth #';
            app.BoothEditField = uieditfield(app.AcclimateUIFigure, 'numeric');
            app.BoothEditField.Limits = [1 8];
            app.BoothEditField.ValueChangedFcn = createCallbackFcn(app, @BoothEditFieldValueChanged, true);
            app.BoothEditField.Value = 1;
            app.rewarddurationsecEditFieldLabel = uilabel(app.AcclimateUIFigure);
            app.rewarddurationsecEditFieldLabel.Text = 'reward duration (sec)';
            app.rewarddurationsecEditField = uieditfield(app.AcclimateUIFigure, 'numeric');
            app.rewarddurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @rewarddurationsecEditFieldValueChanged, true);
            app.rewarddurationsecEditField.Value = 3;
            app.ITIminsecEditFieldLabel = uilabel(app.AcclimateUIFigure);
            app.ITIminsecEditFieldLabel.Text = 'ITI min (sec)';
            app.ITIminsecEditField = uieditfield(app.AcclimateUIFigure, 'numeric');
            app.ITIminsecEditField.ValueChangedFcn = createCallbackFcn(app, @ITIminsecEditFieldValueChanged, true);
            app.ITIminsecEditField.Value = 30;
            app.ITImaxsecEditFieldLabel = uilabel(app.AcclimateUIFigure);
            app.ITImaxsecEditFieldLabel.Text = 'ITI max (sec)';
            app.ITImaxsecEditField = uieditfield(app.AcclimateUIFigure, 'numeric');
            app.ITImaxsecEditField.ValueChangedFcn = createCallbackFcn(app, @ITImaxsecEditFieldValueChanged, true);
            app.ITImaxsecEditField.Value = 60;
            app.StartButton = uibutton(app.AcclimateUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RunningStatusLampLabel = uilabel(app.AcclimateUIFigure);
            app.RunningStatusLampLabel.Text = 'Running Status';
            app.RunningStatusLamp = uilamp(app.AcclimateUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Acclimate_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcclimateUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcclimateUIFigure)
        end
    end
end