classdef Housing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HousingUIFigure                matlab.ui.Figure
        BoothEditFieldLabel            matlab.ui.control.Label
        BoothEditField                 matlab.ui.control.NumericEditField
        Lightontime24hrEditFieldLabel  matlab.ui.control.Label
        Lightontime24hrEditField       matlab.ui.control.NumericEditField
        Lightofftime24hrLabel          matlab.ui.control.Label
        Lightofftime24hrEditField      matlab.ui.control.NumericEditField
        HousingLabel                   matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        RunningStatusLampLabel         matlab.ui.control.Label
        RunningStatusLamp              matlab.ui.control.Lamp
    end


    properties (Access = public)
        booth = 1;  % Booth #
        light_on = 7; % Light-on time
        light_off = 21; % Light-off time
        
    end

    methods (Access = private)
    
        function housing(app)
            
           switch app.booth
                case 1
                    arduino_device = 'COM3';
                    disp('booth 1 running');
                case 2
                    arduino_device = 'COM4';
                    disp('booth 2 running');
                case 3
                    arduino_device = 'COM5';
                    disp('booth 3 running');
                case 4
                    arduino_device = 'COM7';
                    disp('booth 4 running');
                case 5
                    arduino_device = 'COM8';
                    disp('booth 5 running');
                case 6
                    arduino_device = 'COM9';
                    disp('booth 6 running');
                case 7
                    arduino_device = 'COM10';
                    disp('booth 7 running');
                case 8
                    arduino_device = 'COM6';
                    disp('booth 8 running');
                    
            end
            
            a = arduino(arduino_device);
            
            configurePin(a, 'D44', 'digitalOutput');
            
            %% Actual experiment
            while true
                
                drawnow;  % Process callbacks that haven't been run
                now = fix(clock);
                time = now(4);  % Get hour
                
                if time > (app.light_on-1) && time < app.light_off  %if time greater than 6 and smaller than 21, light is on
                    writeDigitalPin(a, 'D44', 1);
                    %disp('light is on');
                    
                else 
                    writeDigitalPin(a, 'D44', 0);
                    %disp('light is off');
                
                end
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if (app.RunningStatusLamp.Color == [1 0 0])% If current lamp color is red (app not running) 
               
               app.RunningStatusLamp.Color = [0 1 0]; % Change lamp color to green
               disp(['light on at ', num2str(app.light_on)]);
               disp(['light off at ', num2str(app.light_off)]);
               
               housing(app); % Run housing function
               
            end
        end

        % Value changed function: BoothEditField
        function BoothEditFieldValueChanged(app, event)
            app.booth = app.BoothEditField.Value;
            
        end

        % Value changed function: Lightontime24hrEditField
        function Lightontime24hrEditFieldValueChanged(app, event)
            app.light_on = app.Lightontime24hrEditField.Value;
            
        end

        % Value changed function: Lightofftime24hrEditField
        function Lightofftime24hrEditFieldValueChanged(app, event)
            app.light_off = app.Lightofftime24hrEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HousingUIFigure = uifigure;
            app.HousingUIFigure.Name = 'Housing';
            app.BoothEditFieldLabel = uilabel(app.HousingUIFigure);
            app.BoothEditFieldLabel.Text = 'Booth #';
            app.BoothEditField = uieditfield(app.HousingUIFigure, 'numeric');
            app.BoothEditField.Limits = [1 8];
            app.BoothEditField.ValueChangedFcn = createCallbackFcn(app, @BoothEditFieldValueChanged, true);
            app.BoothEditField.Value = 1;
            app.Lightontime24hrEditFieldLabel = uilabel(app.HousingUIFigure);
            app.Lightontime24hrEditFieldLabel.Text = 'Light on time (24-hr)';
            app.Lightontime24hrEditField = uieditfield(app.HousingUIFigure, 'numeric');
            app.Lightontime24hrEditField.Limits = [0 23];
            app.Lightontime24hrEditField.ValueChangedFcn = createCallbackFcn(app, @Lightontime24hrEditFieldValueChanged, true);
            app.Lightontime24hrEditField.Value = 7;
            app.Lightofftime24hrLabel = uilabel(app.HousingUIFigure);
            app.Lightofftime24hrLabel.Text = 'Light off time (24-hr)';
            app.Lightofftime24hrEditField = uieditfield(app.HousingUIFigure, 'numeric');
            app.Lightofftime24hrEditField.Limits = [0 23];
            app.Lightofftime24hrEditField.ValueChangedFcn = createCallbackFcn(app, @Lightofftime24hrEditFieldValueChanged, true);
            app.Lightofftime24hrEditField.Value = 21;
            app.HousingLabel = uilabel(app.HousingUIFigure);
            app.HousingLabel.Text = 'Housing';
            app.StartButton = uibutton(app.HousingUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RunningStatusLampLabel = uilabel(app.HousingUIFigure);
            app.RunningStatusLampLabel.Text = 'Running Status';
            app.RunningStatusLamp = uilamp(app.HousingUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Housing_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HousingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HousingUIFigure)
        end
    end
end