classdef Shaping_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShapingUIFigure             matlab.ui.Figure
        ShapingLabel                matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        BoothEditFieldLabel         matlab.ui.control.Label
        BoothEditField              matlab.ui.control.NumericEditField
        rewarddurationsecEditFieldLabel  matlab.ui.control.Label
        rewarddurationsecEditField  matlab.ui.control.NumericEditField
        RunningStatusLampLabel      matlab.ui.control.Label
        RunningStatusLamp           matlab.ui.control.Lamp
        Trial1Label                 matlab.ui.control.Label
    end


    properties (Access = private)
        booth = 1;
        reward_time = 2;
        trial = 1;
        
    end

    methods (Access = private)
    
        function shaping(app)
            switch app.booth
                
                case 1
                    arduino_device = 'COM3';
                    disp('booth 1 running');
                case 2
                    arduino_device = 'COM4';
                    disp('booth 2 running');
                case 3
                    arduino_device = 'COM5';
                    disp('booth 3 running');
                case 4
                    arduino_device = 'COM7';
                    disp('booth 4 running');
                case 5
                    arduino_device = 'COM8';
                    disp('booth 5 running');
                case 6
                    arduino_device = 'COM9';
                    disp('booth 6 running');
                case 7
                    arduino_device = 'COM10';
                    disp('booth 7 running');
                case 8
                    arduino_device = 'COM6';
                    disp('booth 8 running');
                    
            end
            
            %Initialize arduino
            a = arduino(arduino_device);
            
            %Define solenoid pin # and configure the pin to digital output
            SOLENOID_PIN = 'D46';
            configurePin(a, SOLENOID_PIN, 'digitalOutput');
            
            sensor_PIN = 'D45';          %sensor
            configurePin(a, sensor_PIN, 'digitalInput');  %set sensor pin to digital input
            configurePin(a, sensor_PIN, 'pullup');        %pull up
            
            % The feeder moves after the sensor is triggered and has the reward present for a defined time duration
                    
            sensorState = 0;
            lastState =0;
            
            
            while true
                drawnow; % Process callbacks that haven't been run
                
                sensorState = readDigitalPin(a, sensor_PIN);
                
                if (sensorState && ~lastState)  %if True(1) && not false(1)
                    fprintf('Unbroken\n');          %lastState = false (0)
                    writeDigitalPin(a, SOLENOID_PIN, 0);   %solenoid is not activated
                
                end
              
                if (~sensorState && lastState)  %if not false(1) && True(1)
                    fprintf('Broken\n');            %sensorState = false (0) if touched
                    writeDigitalPin(a, SOLENOID_PIN, 1);    %solenoid is activated (feeder out)
                    pause(app.reward_time);    %feeder presents for a defined period of time
                
                    current_time = clock;   %show current time
                
                    disp(['sensor triggered at ',num2str(current_time(1)),'-',num2str(current_time(2)), ...
                    '-',num2str(current_time(3)),'  ',num2str(current_time(4)),'h ',num2str(current_time(5)), 'm']);
             
                    app.trial = app.trial+1;
                    app.Trial1Label.Text = ['Trial ', num2str(app.trial)];
                    
                end
              
                lastState = sensorState;
                
            end
        end
        
    end


    methods (Access = private)

        % Value changed function: BoothEditField
        function BoothEditFieldValueChanged(app, event)
            app.booth = app.BoothEditField.Value;
          
        end

        % Value changed function: rewarddurationsecEditField
        function rewarddurationsecEditFieldValueChanged(app, event)
            app.reward_time = app.rewarddurationsecEditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.RunningStatusLamp.Color == [1 0 0]
                
               app.RunningStatusLamp.Color = [0 1 0]; % Turn lamp color to green
                
               disp('Shaping');
               shaping(app);  % Run shaping function
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShapingUIFigure = uifigure;
            app.ShapingUIFigure.Name = 'Shaping';
            app.ShapingLabel = uilabel(app.ShapingUIFigure);
            app.ShapingLabel.Text = 'Shaping';
            app.StartButton = uibutton(app.ShapingUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BoothEditFieldLabel = uilabel(app.ShapingUIFigure);
            app.BoothEditFieldLabel.Text = 'Booth #';
            app.BoothEditField = uieditfield(app.ShapingUIFigure, 'numeric');
            app.BoothEditField.Limits = [1 8];
            app.BoothEditField.ValueChangedFcn = createCallbackFcn(app, @BoothEditFieldValueChanged, true);
            app.BoothEditField.Value = 1;
            app.rewarddurationsecEditFieldLabel = uilabel(app.ShapingUIFigure);
            app.rewarddurationsecEditFieldLabel.Text = 'reward duration (sec)';
            app.rewarddurationsecEditField = uieditfield(app.ShapingUIFigure, 'numeric');
            app.rewarddurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @rewarddurationsecEditFieldValueChanged, true);
            app.rewarddurationsecEditField.Value = 3;
            app.RunningStatusLampLabel = uilabel(app.ShapingUIFigure);
            app.RunningStatusLampLabel.Text = 'Running Status';
            app.RunningStatusLamp = uilamp(app.ShapingUIFigure);
            app.Trial1Label = uilabel(app.ShapingUIFigure);
            app.Trial1Label.Text = 'Trial 1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Shaping_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShapingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShapingUIFigure)
        end
    end
end